classdef StewartPlatform_JointKinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MinimumDistancemmGaugeLabel  matlab.ui.control.Label
        MinimumDistancemmGauge       matlab.ui.control.LinearGauge
        TabGroup                     matlab.ui.container.TabGroup
        InitializationTab            matlab.ui.container.Tab
        AtHomePosLabel               matlab.ui.control.Label
        HexapodCartesianPosePanel_2  matlab.ui.container.Panel
        PitchEditField_5             matlab.ui.control.NumericEditField
        PitchEditField_5Label        matlab.ui.control.Label
        RollEditField_7              matlab.ui.control.NumericEditField
        RollEditField_7Label         matlab.ui.control.Label
        GatherCurrentPlatformPoseButton_3  matlab.ui.control.Button
        ForTestingLabel              matlab.ui.control.Label
        HexapodCartesianPosePanel    matlab.ui.container.Panel
        PitchEditField_4             matlab.ui.control.NumericEditField
        PitchEditField_4Label        matlab.ui.control.Label
        RollEditField_6              matlab.ui.control.NumericEditField
        RollEditField_6Label         matlab.ui.control.Label
        GatherCurrentPlatformPoseButton  matlab.ui.control.Button
        LoadCustomUJointMeshButton   matlab.ui.control.Button
        PleasecompletethefollowingLabel  matlab.ui.control.Label
        ZeroJointAnglesButton        matlab.ui.control.Button
        LoadHexapodGeometryButton    matlab.ui.control.Button
        StaticTab                    matlab.ui.container.Tab
        MeasuredUjointPositionDataPanel  matlab.ui.container.Panel
        degLabel_4                   matlab.ui.control.Label
        SecondAngleEditField_2       matlab.ui.control.NumericEditField
        SecondAngleEditField_2Label  matlab.ui.control.Label
        degLabel_3                   matlab.ui.control.Label
        FirstAngleEditField_2        matlab.ui.control.NumericEditField
        FirstAngleEditField_2Label   matlab.ui.control.Label
        CurrentJointPoseButton       matlab.ui.control.Button
        ComputedUJointPositionDataPanel  matlab.ui.container.Panel
        JointNumberDropDown_3        matlab.ui.control.DropDown
        JointNumberDropDown_3Label   matlab.ui.control.Label
        GatherCurrentPlatformPoseButton_2  matlab.ui.control.Button
        ORLabel                      matlab.ui.control.Label
        SecondAngleEditField         matlab.ui.control.NumericEditField
        SecondAngleEditFieldLabel    matlab.ui.control.Label
        FirstAngleEditField          matlab.ui.control.NumericEditField
        FirstAngleEditFieldLabel     matlab.ui.control.Label
        KinematicOuputAnglesLabel    matlab.ui.control.Label
        EnterButton                  matlab.ui.control.Button
        zmEditField                  matlab.ui.control.NumericEditField
        zmEditFieldLabel             matlab.ui.control.Label
        ymEditField                  matlab.ui.control.NumericEditField
        ymEditFieldLabel             matlab.ui.control.Label
        xmEditField                  matlab.ui.control.NumericEditField
        xmEditFieldLabel             matlab.ui.control.Label
        RzdegEditField               matlab.ui.control.NumericEditField
        RzdegLabel                   matlab.ui.control.Label
        RydegEditField               matlab.ui.control.NumericEditField
        RydegLabel                   matlab.ui.control.Label
        RxdegEditField               matlab.ui.control.NumericEditField
        RxdegLabel                   matlab.ui.control.Label
        DynamicTab                   matlab.ui.container.Tab
        StartStopButton              matlab.ui.control.StateButton
        SelectJointButtonGroup       matlab.ui.container.ButtonGroup
        Platform3Button              matlab.ui.control.ToggleButton
        Platform2Button              matlab.ui.control.ToggleButton
        Platform1Button              matlab.ui.control.ToggleButton
        Base3Button                  matlab.ui.control.ToggleButton
        Base2Button                  matlab.ui.control.ToggleButton
        Base1Button                  matlab.ui.control.ToggleButton
        UIAxes2                      matlab.ui.control.UIAxes
        ExtrasTab                    matlab.ui.container.Tab
        AnimateJogUJointPanel        matlab.ui.container.Panel
        DefaultisBaseJointLabel      matlab.ui.control.Label
        PlatformJointCheckBox        matlab.ui.control.CheckBox
        SecondangleSlider            matlab.ui.control.Slider
        SecondangleSliderLabel       matlab.ui.control.Label
        FirstangleSlider             matlab.ui.control.Slider
        Label                        matlab.ui.control.Label
        StewartPlatformUJointKinematicsCheckLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MSH_Top 
        MSH_Bottom
        Theta = 0
        Phi = 0
        isStreaming = false
        hex_obj = struct
        hex_setup = struct
        Current_JointAngles = zeros(7,2)
        Zeroing_JointAngles = zeros(7,2)
        yokeCollisionTable
        min_dist_ComputedHistory = [];
        min_dist_MeasuredHistory =[];
    end

    
    methods (Access = private)
        
        function updateMeshPlot(app,base)
            MSH_C = app.MSH_Top;
            MSH_D = app.MSH_Bottom;
        

            % Rotn = eul2rotm(deg2rad([app.Phi, app.Theta, 0]), 'XYZ');
            % TF = [Rotn [0; 0; 0]; 0 0 0 1];
        
            if ~base
                Rotn = eul2rotm(deg2rad([app.Phi, app.Theta, 0]), 'XYZ');
                TF = [Rotn [0; 0; 0]; 0 0 0 1];
                for n = 1:length(MSH_D)
                    MSH_D(n).Pose = TF;
                end
            else
                Rotn = eul2rotm(deg2rad([app.Theta, app.Phi, 0]), 'XYZ');
                TF = [Rotn [0; 0; 0]; 0 0 0 1];
                for n = 1:length(MSH_C)
                    MSH_C(n).Pose = TF;
                end
            end
        
            [iscoll, mindist, witnesspoints] = checkYokeCollision(MSH_C, MSH_D);
        
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
        
            for n = 1:length(MSH_C)
                [~, h1] = show(MSH_C(n), 'Parent', app.UIAxes);
                h1.FaceColor = 'b'; h1.EdgeColor = [0 0 0.2]; h1.FaceAlpha = 0.3;
        
                [~, h2] = show(MSH_D(n), 'Parent', app.UIAxes);
                h2.FaceColor = 'r'; h2.EdgeColor = [0.2 0 0]; h2.FaceAlpha = 0.3;
            end
        
            % Show witness points
            if ~isempty(witnesspoints)
                plot3(app.UIAxes, witnesspoints(1,:), witnesspoints(2,:), witnesspoints(3,:), ...
                      '-og', 'MarkerFaceColor', 'g', 'MarkerSize', 8, 'LineWidth', 2);
            end
        
            % Set axes
            view(app.UIAxes, [-51.2, 18.2]);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XLim = [-60, 100];
            app.UIAxes.YLim = [-60, 100];
            app.UIAxes.ZLim = [-50, 100];
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
        
            % Display distance
            if ~iscoll
                text(app.UIAxes, witnesspoints(1,1), witnesspoints(2,1), witnesspoints(3,1), ...
                     ['Separation Distance = ' num2str(mindist, '%.2f') ' mm']);
            else
                text(app.UIAxes, 0, -30, -40, ...
                     ['Separation Distance = ' num2str(mindist, '%.2f') ' mm']);
            end

            app.MinimumDistancemmGauge.Value = mindist;  
        end
        
        function updateMeshPlot_dynamic_Base(app,numb)
            % DIGITAL SHADOW YASY

            Angle1_correction = 2.31;
            Angle2_correction = 1.37;

            app.MinimumDistancemmGauge.Visible = "off";
            app.MinimumDistancemmGaugeLabel.Visible = "off";

            maxSteps = 1000;
            mindistHistory_measured = nan(1, maxSteps);
            step = 1;
            MSH_C = app.MSH_Top;
            MSH_D = app.MSH_Bottom;
            
            % Initialize mesh handles
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            hC = gobjects(1, length(MSH_C));
            hD = gobjects(1, length(MSH_D));
            for n = 1:length(MSH_C)
                [~, hC(n)] = show(MSH_C(n), 'Parent', app.UIAxes);
                hC(n).FaceColor = 'b'; hC(n).EdgeColor = [0 0 0.2]; hC(n).FaceAlpha = 0.3;
            
                [~, hD(n)] = show(MSH_D(n), 'Parent', app.UIAxes);
                hD(n).FaceColor = 'r'; hD(n).EdgeColor = [0.2 0 0]; hD(n).FaceAlpha = 0.3;
            end
            view(app.UIAxes, [-51.2, 18.2]);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XLim = [-60, 100];
            app.UIAxes.YLim = [-60, 100];
            app.UIAxes.ZLim = [-50, 100];
            
            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');
            
            minDistLine_Calc = plot(app.UIAxes2, nan, nan, '-', ...
                'Color', [0 0 0.6], 'LineWidth', 1.5, 'DisplayName', 'Calculated');  % dark blue
            
            minDistLine_Measured = plot(app.UIAxes2, nan, nan, '-', ...
                'Color', [0.6 0 0], 'LineWidth', 1.5, 'DisplayName', 'Measured');   % dark red
            
            %title(app.UIAxes2, 'Min Distance Over Time');
            %xlabel(app.UIAxes2, 'Time Step');
            ylabel(app.UIAxes2, 'Min Distance [mm]');
            ylim(app.UIAxes2, [0 5]);  
            legend(app.UIAxes2, 'show');
            grid(app.UIAxes2, 'on');

            timeArray = [];
            collisionDist_Calc_array = [];
            collisionDist_Measured_array = [];
            calcAngle1_array = [];
            calcAngle2_array = [];
            measAngle1_array = [];
            measAngle2_array = [];


            clear serialObj
            delete(instrfindall);
            delete(instrfind);
            
            serialObj = serialport("COM10", 115200);  
            configureTerminator(serialObj, "LF");
            flush(serialObj);

            fs = 10;  % Hz
            beta_stationary = 0.08; 
            beta_joint = 0.02;
            AHRS_Hex = MadgwickAHRS('SamplePeriod', 1/fs, 'Beta', beta_stationary);  
            AHRS_joint = MadgwickAHRS('SamplePeriod', 1/fs, 'Beta', beta_joint); 

            % Initialize Filter!
            tStart = tic;
            gotHex = false;
            gotJoint = false;         
            
            while ~(gotHex && gotJoint)
                if serialObj.NumBytesAvailable > 0
                    line = readline(serialObj);
                    data = str2double(split(line, ','));
                    
                    if length(data) == 10
                        imuID_log = data(1);
                        accel = data(2:4);
                        accel(3) = -accel(3);
                        accel(2) = -accel(2);
            
                        roll  = atan2(accel(2), accel(3)) * 180/pi;
                        pitch = atan2(accel(1), sqrt(accel(2)^2 + accel(3)^2)) * 180/pi;
            
                        quat = eul2quat([deg2rad(roll), deg2rad(pitch), 0], 'XYZ');
            
                        if imuID_log == 0 && ~gotHex
                            AHRS_Hex.Quaternion = quat;
                            calcAngle1_array(end+1) = roll;
                            calcAngle1_array(end+1) = pitch;
                            gotHex = true;
                            fprintf("Initialized HEX: roll = %.2f, pitch = %.2f\n", roll, pitch);
                        elseif imuID_log == numb && ~gotJoint
                            quat(3) = -quat(3);  % correct Z
                            AHRS_joint.Quaternion = quat;
                            measAngle1_array(end+1) = roll;
                            measAngle2_array(end+1) = pitch;
                            gotJoint = true;
                            fprintf("Initialized JOINT: roll = %.2f, pitch = %.2f\n", roll, pitch);
                        end
                    end
                end
            end
             
            pause(3)


            while app.isStreaming 
                %tStart = tic;
                line = readline(serialObj);
                data = str2double(split(line, ','));
                if length(data) == 10
                    
                    imuID_log = data(1);
                    accel = data(2:4);
                    gyro  = data(5:7);
                    
                    %mag   = data(8:10);
                    accel(3) = - accel(3);
                    accel(2) = - accel(2);

                    gyro(2) = -gyro(2);
                    gyro(3) = -gyro(3);

                    gyro_rad = deg2rad(gyro(:)');

                    if imuID_log == 0
                        %roll = atan2(accel(2),accel(3))*180/pi;
                        %pitch = atan2(accel(1),sqrt(accel(2)*accel(2)+accel(3)*accel(3)))*180/pi;
                        if abs(mean(gyro_rad)) >0.0003
                              AHRS_Hex.Beta = 0.15;
                        else
                              AHRS_Hex.Beta = 0.08;
                        end
                        
                        AHRS_Hex.UpdateIMU(gyro_rad,accel');
                        eul = quat2eul(AHRS_Hex.Quaternion,'XYZ');
                        roll = rad2deg(eul(1));
                        pitch = -rad2deg(eul(2));

                        pose = [0,0,0,deg2rad(roll),deg2rad(pitch),0]';
                        app.hex_obj.pose =pose;
                        [firstAngle_base,secondAngle_base] = InverseKinematicsEfficient(app.hex_obj,app.hex_setup,numb);
                        firstAngle_calc = rad2deg(firstAngle_base);
                        secondAngle_calc = rad2deg(secondAngle_base);
                        collisionDist_Calc = app.yokeCollisionTable(firstAngle_calc,secondAngle_calc);

                        %gyro = deg2rad(gyro(:)'); 
                        % filter.update(gyro, accel,NaN); % No Magnetometer for OBVIOUS reasons duh
                        % eul_filt = quat2eul(filter.qEst);
                        collisionDist_Calc_array(end+1) = collisionDist_Calc;
                        calcAngle1_array(end+1) = firstAngle_calc;
                        calcAngle2_array(end+1) = secondAngle_calc;

                        
                    else
                        % angle1_measured = atan2(accel(2),accel(3))*180/pi;
                        % angle2_measured = atan2(accel(1),sqrt(accel(2)*accel(2)+accel(3)*accel(3)))*180/pi;
                        if abs(mean(gyro_rad)) >0.0005
                              AHRS_Hex.Beta = 0.025;
                        else
                              AHRS_Hex.Beta = 0.015;
                        end
                        AHRS_joint.UpdateIMU(gyro_rad,accel);
                        eul = quat2eul(AHRS_joint.Quaternion,'XYZ');
                        angle1_measured = rad2deg(eul(1));
                        angle2_measured = rad2deg(-eul(2));

                        angle1_measured = abs(angle1_measured) + Angle1_correction;
                        angle2_measured = abs(angle2_measured) - Angle2_correction;
                        
                        Rotn = eul2rotm(deg2rad([angle2_measured, angle1_measured, 0]), 'XYZ'); 

                        TF = [Rotn [0; 0; 0]; 0 0 0 1];
                      
                         
                        mindist_measured = app.yokeCollisionTable(angle1_measured,angle2_measured);
                        collisionDist_Measured_array(end+1) = mindist_measured;
                        measAngle1_array(end+1) = angle1_measured;
                        measAngle2_array(end+1) = angle2_measured;

                        % % Delete old dynamic mesh handles
                        % delete(hD);  % hD was created before the loop
                        % 
                        % % Redraw dynamic mesh (only)
                        % hD = gobjects(1, length(MSH_D));
                        % for n = 1:length(MSH_D)
                        %     MSH_D(n).Pose = TF;
                        %     [~, hD(n)] = show(MSH_D(n), 'Parent', app.UIAxes);
                        %     hD(n).FaceColor = 'r';
                        %     hD(n).EdgeColor = [0.2 0 0];
                        %     hD(n).FaceAlpha = 0.3;
                        % end

                        for n = 1:length(MSH_D)
                            MSH_D(n).Pose = TF;
                            delete(hD(n));  
                            [~, hD(n)] = show(MSH_D(n), 'Parent', app.UIAxes);  
                            hD(n).FaceColor = 'r';
                            hD(n).EdgeColor = [0.2 0 0];
                            hD(n).FaceAlpha = 0.3;
                        end


                        timeArray(end+1) = step;                        
                        set(minDistLine_Calc, 'XData', timeArray, 'YData', collisionDist_Calc_array);
                        set(minDistLine_Measured, 'XData', timeArray, 'YData', collisionDist_Measured_array);
                        step =step+1;
                        drawnow limitrate nocallbacks;
                     

                    end

                    
                 end
                 
                %elapsed = toc(tStart);  % in seconds
                %fprintf("Loop time: %.2f ms\n", elapsed * 1000);
            end
            clear serialObj
            [filename, pathname] = uiputfile('*.mat', 'Save Collision Data As');
            if isequal(filename,0)
                disp('User canceled saving.');
            else
                logData = struct( ...
                    'Time', timeArray, ...
                    'Collision_Calculated', collisionDist_Calc_array, ...
                    'Collision_Measured', collisionDist_Measured_array, ...
                    'Angles_Calculated', struct('Angle1', calcAngle1_array, 'Angle2', calcAngle2_array), ...
                    'Angles_Measured', struct('Angle1', measAngle1_array, 'Angle2', measAngle2_array));
            
                save(fullfile(pathname, filename), 'logData');
                disp(['Data saved to: ', fullfile(pathname, filename)]);
            end

        end
        
        function updateMeshPlot_dynamic_Plat(app,numb)
            app.MinimumDistancemmGauge.Visible = "off";
            app.MinimumDistancemmGaugeLabel.Visible = "off";
        
            MSH_C = app.MSH_Top;     
            MSH_D = app.MSH_Bottom;  
        
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            hC = gobjects(1, length(MSH_C));
            hD = gobjects(1, length(MSH_D));
            for n = 1:length(MSH_C)
                [~, hC(n)] = show(MSH_C(n), 'Parent', app.UIAxes);
                hC(n).FaceColor = 'b'; hC(n).EdgeColor = [0 0 0.2]; hC(n).FaceAlpha = 0.3;
        
                [~, hD(n)] = show(MSH_D(n), 'Parent', app.UIAxes);
                hD(n).FaceColor = 'r'; hD(n).EdgeColor = [0.2 0 0]; hD(n).FaceAlpha = 0.3;
            end
            view(app.UIAxes, [-51.2, 18.2]);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XLim = [-60, 100];
            app.UIAxes.YLim = [-60, 100];
            app.UIAxes.ZLim = [-50, 100];
        
            cla(app.UIAxes2); hold(app.UIAxes2, 'on');
            minDistLine_Calc = plot(app.UIAxes2, nan, nan, '-', 'Color', [0 0 0.6], 'LineWidth', 1.5, 'DisplayName', 'Calculated');
            minDistLine_Meas = plot(app.UIAxes2, nan, nan, '-', 'Color', [0.6 0 0], 'LineWidth', 1.5, 'DisplayName', 'Measured');
            ylabel(app.UIAxes2, 'Min Distance [mm]');
            ylim(app.UIAxes2, [0 5]);
            legend(app.UIAxes2, 'Location', 'northeast'); grid(app.UIAxes2, 'on');
        
            calcAngle1_array = []; calcAngle2_array = [];
            meas1_Ang1_array = []; meas1_Ang2_array = [];
            meas2_Ang1_array = []; meas2_Ang2_array = [];
            fused_Ang1_array = []; fused_Ang2_array = [];
        
            t0    = tic;            
            tCalc = []; yCalc = []; 
            tMeas = []; yMeas = []; 
            fs_plot = 10;           
        
            clear serialObj; delete(instrfindall); delete(instrfind);
            serialObj = serialport("COM10", 115200);
            configureTerminator(serialObj, "LF"); flush(serialObj);
        
            fs = 10;  % Hz
            AHRS_Hex  = MadgwickAHRS('SamplePeriod', 1/fs, 'Beta', 0.08);
            AHRS_1    = MadgwickAHRS('SamplePeriod', 1/fs, 'Beta', 0.05);
            AHRS_2    = MadgwickAHRS('SamplePeriod', 1/fs, 'Beta', 0.05);
        
            numb1 = 3; 
            numb2 = 6;  
        
            gotHex = false; got1 = false; got2 = false;
            while ~(gotHex && got1 && got2)
                if serialObj.NumBytesAvailable > 0
                    line = readline(serialObj);
                    data = str2double(split(line, ','));
                    if numel(data) == 10
                        imuID = data(1);
                        acc = data(2:4);
        
                        if imuID == 0 && ~gotHex
                            acc(3) = -acc(3); acc(2) = -acc(2);
                            roll  = atan2(acc(2), acc(3)) * 180/pi;
                            pitch = atan2(acc(1), sqrt(acc(2)^2 + acc(3)^2)) * 180/pi;
                            q0 = eul2quat([deg2rad(roll), deg2rad(pitch), 0], 'XYZ');
                            q0(3) = -q0(3);
                            AHRS_Hex.Quaternion = q0; gotHex = true;
                           
        
                        elseif imuID == numb1 && ~got1
                            acc(3) = -acc(3); acc(2) = -acc(2);
                            roll  = atan2(acc(2), acc(3)) * 180/pi;
                            pitch = atan2(acc(1), sqrt(acc(2)^2 + acc(3)^2)) * 180/pi;
                            q0 = eul2quat([deg2rad(roll), deg2rad(pitch), 0], 'XYZ');
                            q0(3) = -q0(3);
                            AHRS_1.Quaternion = q0; got1 = true;
                            
        
                        elseif imuID == numb2 && ~got2
                            roll  = atan2(acc(2), acc(3)) * 180/pi;
                            pitch = -atan2(-acc(1), sqrt(acc(2)^2 + acc(3)^2)) * 180/pi;
                            q0 = eul2quat([deg2rad(roll), deg2rad(pitch), 0], 'XYZ');
                            q0(3) = -q0(3);
                            AHRS_2.Quaternion = q0; got2 = true;
                  
                           
                        end
                    end
                end
            end
            pause(3);

            warmup = 6;  
        
            while app.isStreaming
                if serialObj.NumBytesAvailable == 0
                    drawnow limitrate; continue;
                end
        
                line = readline(serialObj);
                data = str2double(split(line, ','));
                if numel(data) ~= 10, continue; end
        
                imuID = data(1);
                acc = data(2:4); gyro = data(5:7);
        
                if imuID == 0
                    acc(3) = -acc(3); acc(2) = -acc(2);
                    gyro(2) = -gyro(2); gyro(3) = -gyro(3);
                    gyro_rad = deg2rad(gyro);
        
                    if abs(mean(gyro_rad)) > 0.0003, AHRS_Hex.Beta = 0.15; else, AHRS_Hex.Beta = 0.08; end
                    AHRS_Hex.UpdateIMU(gyro_rad, acc);
                    eul = quat2eul(AHRS_Hex.Quaternion, 'XYZ');
                    roll_hex  = rad2deg(eul(1));
                    pitch_hex = -rad2deg(eul(2));
        
                    pose = [0,0,0,deg2rad(roll_hex),deg2rad(pitch_hex),0]';
                    app.hex_obj.pose = pose;
        
                    [a1_calc_rad, a2_calc_rad] = InverseKinematicsEfficient2(app.hex_obj, app.hex_setup, numb);
                    a1_calc = rad2deg(a1_calc_rad);
                    a2_calc = rad2deg(a2_calc_rad);
                    md_calc = app.yokeCollisionTable(a1_calc, a2_calc);
        
                    calcAngle1_array(end+1) = a1_calc;
                    calcAngle2_array(end+1) = a2_calc;
        
                    tCalc(end+1) = toc(t0);
                    yCalc(end+1) = md_calc;
        
                elseif imuID == numb1 || imuID == numb2
                    if imuID == numb1
                        acc(3) = -acc(3); acc(2) = -acc(2);
                        gyro(2) = -gyro(2); gyro(3) = -gyro(3);
                        gyro_rad = deg2rad(gyro(:)');
                        if abs(mean(gyro_rad)) > 0.0003, AHRS_1.Beta = 0.10; else, AHRS_1.Beta = 0.03; end
                        AHRS_1.UpdateIMU(gyro_rad, acc);
                        e1 = quat2eul(AHRS_1.Quaternion, 'XYZ');
                        ang1_1 = rad2deg(e1(1));
                        ang2_1 = rad2deg(e1(2));
                        meas1_Ang1_array(end+1) = ang1_1; 
                        meas1_Ang2_array(end+1) = ang2_1;
        
                    else 
                        gyro_rad = deg2rad(gyro(:)');
                        if abs(mean(gyro_rad)) > 0.0003, AHRS_2.Beta = 0.10; else, AHRS_2.Beta = 0.03; end
                        AHRS_2.UpdateIMU(gyro_rad, acc);
                        e2 = quat2eul(AHRS_2.Quaternion, 'XYZ');
                        ang1_2 = rad2deg(e2(1));
                        ang2_2 = rad2deg(e2(2));
                        meas2_Ang1_array(end+1) = ang1_2; 
                        meas2_Ang2_array(end+1) = ang2_2;
                    end
                end

                ready1 = ~isempty(meas1_Ang1_array);
                ready2 = ~isempty(meas2_Ang1_array);
                if ready1 && ready2 && imuID ~= 0
                    top_roll  = meas1_Ang1_array(end);
                    top_pitch = meas1_Ang2_array(end);
                    bot_roll  = meas2_Ang1_array(end);
                    bot_pitch = meas2_Ang2_array(end);
        
                    % Platform 1
                    % a1_meas = - ( (top_pitch - bot_roll) + 2.23 );
                    % a2_meas = abs( -top_roll + bot_pitch - 2.66 );

                    % Platform 2
                    % a1_meas =   abs(-top_pitch + bot_roll -2.94);
                    % a2_meas = abs(top_roll + bot_pitch -1.66);

                    % Platform 3
                    % top_roll
                    % top_pitch
                    % bot_roll
                    % bot_pitch
                    a1_meas =   abs(top_pitch - bot_roll -2.44);
                    a2_meas = abs(top_roll - bot_pitch -0.17);
        
                    fused_Ang1_array(end+1) = a1_meas;
                    fused_Ang2_array(end+1) = a2_meas;
        
                    Rotn = eul2rotm(deg2rad([a2_meas, a1_meas, 0]), 'XYZ');
                    TF = [Rotn [0;0;0]; 0 0 0 1];
        
                    for n = 1:length(MSH_C)
                        MSH_C(n).Pose = TF;
                        delete(hC(n));
                        [~, hC(n)] = show(MSH_C(n), 'Parent', app.UIAxes);
                        hC(n).FaceColor = 'b'; hC(n).EdgeColor = [0 0 0.2]; hC(n).FaceAlpha = 0.3;
                    end
        
                    md_meas = app.yokeCollisionTable(a1_meas, a2_meas);
        
                    % timestamp + buffer (measured)
                    tMeas(end+1) = toc(t0);
                    yMeas(end+1) = md_meas;
                end
        
                if numel(tCalc) > warmup && numel(tMeas) > warmup
                    tCalc_use = tCalc(warmup+1:end);
                    yCalc_use = yCalc(warmup+1:end);
                    tMeas_use = tMeas(warmup+1:end);
                    yMeas_use = yMeas(warmup+1:end);
                    
                    t0_shift = min([tCalc_use(1), tMeas_use(1)]);
                    tCalc_use = tCalc_use - t0_shift;
                    tMeas_use = tMeas_use - t0_shift;

                    if numel(tCalc) >= 2 && numel(tMeas) >= 2
                        t_lo  = max(tCalc_use(1), tMeas_use(1));
                        t_now = min([tCalc_use(end), tMeas_use(end)]);
                        if t_now > t_lo
                            t_grid = t_lo : 1/fs_plot : t_now;
                
                            yc = interp1(tCalc_use, yCalc_use, t_grid, 'linear');   % no 'extrap'
                            ym = interp1(tMeas_use, yMeas_use, t_grid, 'linear');
                
                            set(minDistLine_Calc, 'XData', t_grid, 'YData', yc);
                            set(minDistLine_Meas, 'XData', t_grid, 'YData', ym);
                            drawnow limitrate;
                        end
                    end
                end
            end
        
            clear serialObj
            [filename, pathname] = uiputfile('*.mat', 'Save PLATFORM Collision Data As');
            if isequal(filename,0)
                disp('User canceled saving.');
            else
                logData = struct( ...
                    'Time_Calc', tCalc, ...
                    'Collision_Calculated', yCalc, ...
                    'Time_Meas', tMeas, ...
                    'Collision_Measured', yMeas, ...
                    'Angles_Calculated', struct('Angle1', calcAngle1_array, 'Angle2', calcAngle2_array), ...
                    'Angles_Measured_IMU1', struct('Angle1', meas1_Ang1_array, 'Angle2', meas1_Ang2_array), ...
                    'Angles_Measured_IMU2', struct('Angle1', meas2_Ang1_array, 'Angle2', meas2_Ang2_array), ...
                    'Angles_Measured_Fused', struct('Angle1', fused_Ang1_array, 'Angle2', fused_Ang2_array) ...
                );
                save(fullfile(pathname, filename), 'logData');
                disp(['Data saved to: ', fullfile(pathname, filename)]);
            end

        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            load("yoke_meshes.mat"); 
            app.MSH_Top = MSH_C;
            app.MSH_Bottom = MSH_D;   

            app.updateMeshPlot(1); 

            tempHexData.the_drB    = 44 * pi/180;
            tempHexData.the_roffB  = 76 * pi/180;
            tempHexData.the_drP    = 26.98 * pi/180;
            tempHexData.the_roffP  = 93.2 * pi/180;
            tempHexData.plat_r     = 0.186;
            tempHexData.base_r     = 0.335788;
            tempHexData.dL = 0.3048;
            tempHexData.L0 = 0.583438;
            tempHexData.home_z     = -0.8255;
            tempHexData.Base_Zlink = 0.07235;
            tempHexData.Platform_Zlink = 0.0406;
            [app.hex_obj,app.hex_setup]= InitializeHexapodObject(tempHexData);

            table_temp = load('lookupTableInterpolant.mat');
            app.yokeCollisionTable = table_temp.jointCollisionInterp;

            addpath('madgwick_algorithm_matlab');  
            addpath('madgwick_algorithm_matlab/quaternion_library');  

        end

        % Value changing function: FirstangleSlider
        function FirstangleSliderValueChanging(app, event)
            app.Theta = event.Value;
            checkValue = app.PlatformJointCheckBox.Value;
            app.updateMeshPlot(checkValue);
            
        end

        % Value changing function: SecondangleSlider
        function SecondangleSliderValueChanging(app, event)
            app.Phi = event.Value;
            checkValue = app.PlatformJointCheckBox.Value;
            app.updateMeshPlot(checkValue);
            
        end

        % Button pushed function: LoadCustomUJointMeshButton
        function LoadCustomUJointMeshButtonPushed(app, event)
            [fileName, filePath] = uigetfile('*.mat', 'Select a .mat file with your MSH data');
            if isequal(fileName, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
                return;
            end
            
            fileContent = load(fullfile(filePath, fileName));
            vars = fieldnames(fileContent);
            
            [topIdx, ok1] = listdlg('PromptString', 'Select Top Mesh:', ...
                                    'SelectionMode', 'single', ...
                                    'ListString', vars, ...
                                    'Name', 'Select MSH_Top');
            if ~ok1
                uialert(app.UIFigure, 'No selection made for MSH_Top.', 'Cancelled');
                return;
            end
        
            [bottomIdx, ok2] = listdlg('PromptString', 'Select Bottom Mesh:', ...
                                       'SelectionMode', 'single', ...
                                       'ListString', vars, ...
                                       'Name', 'Select MSH_Bottom');
            if ~ok2
                uialert(app.UIFigure, 'No selection made for MSH_Bottom.', 'Cancelled');
                return;
            end
    
            app.MSH_Top = fileContent.(vars{topIdx});
            app.MSH_Bottom = fileContent.(vars{bottomIdx});
 
            uialert(app.UIFigure, 'Custom meshes successfully loaded','Success');

            app.updateMeshPlot(); 

        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)

            if app.StartStopButton.Value 
                app.isStreaming = true;
                selectedJoint = app.SelectJointButtonGroup.SelectedObject.Text;
                %collisionPlotBoolean = app.WithCollisionMeshPlotLessEfficientCheckBox.Value;
                
                if startsWith(selectedJoint,'Base')
                     numb = sscanf(selectedJoint,'Base %d');
                     numb = numb;
                     app.updateMeshPlot_dynamic_Base(numb);
                elseif startsWith(selectedJoint,'Platform')
                     numb = sscanf(selectedJoint,'Platform %d');
                     numb = numb+3;
                     app.updateMeshPlot_dynamic_Plat(numb);
                end  
            else                          
                app.isStreaming = false;
            end
            
        end

        % Button pushed function: LoadHexapodGeometryButton
        function LoadHexapodGeometryButtonPushed(app, event)
             prompts = { ...
            'Base angle between pairs (deg):', ...
            'Base angle between off-pairs (deg):', ...
            'Platform angle between pairs (deg):', ...
            'Platform angle between off-pairs (deg):', ...
            'Platform radius (m):', ...
            'Base radius (m):', ...
            'Max stroke length (m):', ...
            'Resting link length (m):', ...
            'Home Z offset (m):', ...
            'Base Z link height (m):', ...
            'Platform Z link height (m):'};
    
        defaultVals = { ...
            '44', ...
            '76', ...
            '26.98', ...
            '93.2', ...
            '0.186', ...
            '0.335788', ...
            '0.3048', ...
            '0.583438', ... 
            '-0.8255', ...
            '0.07235', ...
            '0.0406'};
    
        userInput = inputdlg(prompts, 'Enter Hexapod Geometry', [1 50], defaultVals);
    
        if isempty(userInput)
            return;
        end
    
        tempHexData.the_drB    = str2double(userInput{1}) * pi/180;
        tempHexData.the_roffB  = str2double(userInput{2}) * pi/180;
        tempHexData.the_drP    = str2double(userInput{3}) * pi/180;
        tempHexData.the_roffP  = str2double(userInput{4}) * pi/180;
        tempHexData.plat_r     = str2double(userInput{5});
        tempHexData.base_r     = str2double(userInput{6});
        tempHexData.dL = str2double(userInput{7});
        tempHexData.L0 = str2double(userInput{8});
        tempHexData.home_z     = str2double(userInput{9});
        tempHexData.Base_Zlink = str2double(userInput{10});
        tempHexData.Platform_Zlink = str2double(userInput{11});
       
    
        [app.hex_obj,app.hex_setup]= InitializeHexapodObject(tempHexData);
    
        uialert(app.UIFigure, 'Hexapod configuration loaded!', 'Success');
                
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            z =app.zmEditField.Value;                   
            y = app.ymEditField.Value;                
            x = app.xmEditField.Value;             
            Rz =app.RzdegEditField.Value;                
            Ry = app.RydegEditField.Value;                 
            Rx = app.RxdegEditField.Value;
            pose = [x,y,z,deg2rad(Rx),deg2rad(Ry),deg2rad(Rz)]';
            app.hex_obj.pose =pose;
            app.hex_obj = InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
            
            JointNumberSelected = app.JointNumberDropDown_3.Value;
            
            if startsWith(JointNumberSelected,'Base')
                numbBase = sscanf(JointNumberSelected,'Base #%d');
                switch numbBase
                    case 1
                        numbBase = 1;
                    case 2
                        numbBase = 3;
                    case 3
                        numbBase = 5;
                    otherwise
                        error('Unexpected numbBase value: %d', numbBase);
                end
                                
                app.Theta = rad2deg(app.hex_obj.joint_AB.angle_theta(numbBase));
                app.Phi = rad2deg(app.hex_obj.joint_AB.angle_phi(numbBase));
                app.updateMeshPlot(0);
            elseif startsWith(JointNumberSelected,'Platform')
                numbPlat = sscanf(JointNumberSelected,'Platform #%d');
                switch numbPlat
                    case 1
                        numbPlat = 1;
                    case 2
                        numbPlat = 3;
                    case 3
                        numbPlat = 5;
                    otherwise
                        error('Unexpected numbBase value: %d', numbPlat);
                end
                app.Theta = rad2deg(app.hex_obj.joint_CD.angle_psi(numbPlat));
                app.Phi = rad2deg(app.hex_obj.joint_CD.angle_alpha(numbPlat));
                app.updateMeshPlot(1);
            end
            app.FirstAngleEditField.Value = app.Theta;
            app.SecondAngleEditField.Value = app.Phi;

        end

        % Button pushed function: CurrentJointPoseButton
        function CurrentJointPoseButtonPushed(app, event)
            popup = uifigure('Name', 'Please Wait', 'Position', [500 500 300 100], ...
                 'WindowStyle', 'modal');
            uilabel(popup, 'Text', 'Collecting data and computing angles...', ...
                'Position', [20 40 260 22], 'FontSize', 14);
            drawnow;
            imuID_Selected =app.JointNumberDropDown_3.Value;

            if startsWith(imuID_Selected,'Base')
                joint_angles =GatherArduinoData_BaseJoint();
                joint_angles(isnan(joint_angles)) = 0;
                close(popup);
                numbBase = sscanf(imuID_Selected,'Base #%d');
                % 1 & 2 & 3 Base joint
                numbBase_temp = numbBase;
                numbBase = numbBase+1;
                app.Theta = abs(joint_angles(numbBase,1))-app.Zeroing_JointAngles(numbBase_temp,1);
                app.Phi = abs(joint_angles(numbBase,2))-app.Zeroing_JointAngles(numbBase_temp,2);
                app.updateMeshPlot(0);

            elseif startsWith(imuID_Selected,'Platform')
                joint_angles_Base =GatherArduinoData_BaseJoint();
                joint_angles_plat =GatherArduinoData_Plat();
                joint_angles_Base(isnan(joint_angles_Base)) = 0;
                joint_angles_plat(isnan(joint_angles_plat)) = 0;

                
                close(popup);
                numbPlat = sscanf(imuID_Selected,'Platform #%d');
       
                % Gather top U-joint attached to Platform (Stationary)
                plat_qhat_firstAngle = joint_angles_Base(numbPlat+1,1) % negative to correct for u_hat opposite
                plat_qhat_secondAngle = joint_angles_Base(numbPlat+1,2) % negative to correct for u_hat opposite

                % 4 & 5 & 6 Base joint
                %app.Theta = abs(unwrap(-joint_angles_plat(numbPlat,1))+plat_qhat_secondAngle); %+app.Zeroing_JointAngles(numbPlat+3,1);
                %app.Phi = abs(unwrap(-joint_angles_plat(numbPlat,2)) +plat_qhat_firstAngle); %+app.Zeroing_JointAngles(numbPlat+3,2);

                app.Theta = unwrap(joint_angles_plat(numbPlat,1));
                app.Phi = unwrap(joint_angles_plat(numbPlat,2));
                % 
                % popup = uifigure('Name', 'Computed Angles', 'Position', [500 500 300 150]);
                % angleText = sprintf(['Theta: %.2f°\n' ...
                %      'Phi: %.2f°\n' ...
                %      'q̂ First Angle: %.2f°\n' ...
                %      'q̂ Second Angle: %.2f°'], ...
                %      app.Theta, app.Phi, plat_qhat_firstAngle, plat_qhat_secondAngle);
                % 
                % uilabel(popup, ...
                %     'Text', angleText, ...
                %     'Position', [20 20 260 120], ...
                %     'FontSize', 14, ...
                %     'HorizontalAlignment', 'left');
                app.Theta = app.Theta + plat_qhat_secondAngle-app.Zeroing_JointAngles(numbPlat+4,1);
                app.Phi = app.Phi + plat_qhat_firstAngle - app.Zeroing_JointAngles(numbPlat+4,2);
                app.updateMeshPlot(1);
            end
            
            %app.Current_JointAngles = joint_angles;
            app.FirstAngleEditField_2.Value = app.Theta;
            app.SecondAngleEditField_2.Value = app.Phi;
        end

        % Callback function
        function JointNumberDropDown_2ValueChanged(app, event)
            value = app.JointNumberDropDown_2.Value;
            load("yoke_meshes.mat"); 
            app.MSH_Top = MSH_C;
            app.MSH_Bottom = MSH_D; 
            if startsWith(value,'Base')
                numbBase = sscanf(value,'Base #%d');
                % 1 & 2 & 3 Base joint
                numbBase = numbBase+1;
                app.Theta = app.Current_JointAngles(numbBase,1);
                app.Phi = app.Current_JointAngles(numbBase,2);
                app.updateMeshPlot(0)

            elseif startsWith(value,'Platform')
                numbPlat = sscanf(value,'Platform #%d');
                numbPlat = numbPlat+4;
                % 4 & 5 & 6 Base joint
                app.Theta = unwrap(app.Current_JointAngles(numbPlat,1)+180);
                app.Phi = unwrap(app.Current_JointAngles(numbPlat,2)-180);
                app.updateMeshPlot(1);     

            end
            app.FirstAngleEditField_2.Value = round(app.Theta, 2);
            app.SecondAngleEditField_2.Value = round(app.Phi, 2);


        end

        % Button pushed function: ZeroJointAnglesButton
        function ZeroJointAnglesButtonPushed(app, event)
            popup = uifigure('Name', 'Please Wait', 'Position', [500 500 300 100], ...
                 'WindowStyle', 'modal');
            uilabel(popup, 'Text', 'Collecting data and computing angles...', ...
                'Position', [20 40 260 22], 'FontSize', 14);
            drawnow;

            joint_anglesPlat_corrected = zeros(3,2);
            joint_anglesBase =GatherArduinoData_BaseJoint();
            joint_anglesPlat =GatherArduinoData_Plat();

            joint_anglesBase(isnan(joint_anglesBase)) = 0;
            joint_anglesPlat(isnan(joint_anglesPlat)) = 0;
            
            close(popup);

            %%% KINEMATICS COMPUTE
            app.RydegEditField.Value = joint_anglesBase(1,2);                 
            app.RxdegEditField.Value = joint_anglesBase(1,1);
            Rx = joint_anglesBase(1,1);
            Ry = joint_anglesBase(1,2);
            pose = [0,0,0,deg2rad(Rx),deg2rad(Ry),0]';
            app.hex_obj.pose =pose;
            app.hex_obj = InverseKinematics_hexapod(app.hex_obj,app.hex_setup);

            first_angleBase =app.hex_obj.joint_AB.angle_theta([1, 3, 5]);
            second_angleBase = app.hex_obj.joint_AB.angle_phi([1, 3, 5]);

            first_anglePlat = app.hex_obj.joint_CD.angle_psi([1, 3, 5]);
            second_anglePlat = app.hex_obj.joint_CD.angle_alpha([1, 3, 5]);

            computed_angles =[rad2deg(first_angleBase') rad2deg(second_angleBase')...
                ;rad2deg(first_anglePlat') rad2deg(second_anglePlat')];

            %%% MEASURED
            % Modify measured plat
            for i=1:3
                plat_qhat_firstAngle = -joint_anglesBase(i+1,1);
                plat_qhat_secondAngle = -joint_anglesBase(i+1,2);

                % 4 & 5 & 6 Base joint
                joint_anglesPlat_corrected(i,1) = abs(unwrap(joint_anglesPlat(i,1)) +plat_qhat_secondAngle);
                joint_anglesPlat_corrected(i,2) = abs(unwrap(-joint_anglesPlat(i,2)) +plat_qhat_firstAngle);           
            end
            %%% MEASURED - COMPUTED
            joint_angles = [joint_anglesBase;joint_anglesPlat_corrected]

            app.Zeroing_JointAngles = abs(joint_angles(2:end,:)) - abs(computed_angles);

            anglePopup = uifigure('Name', 'Joint Angles', 'Position', [500 300 300 250]);
            uitable(anglePopup, ...
                'Data', app.Zeroing_JointAngles , ...
                'ColumnName', {'First Angle (°)', 'Second Angle (°)'}, ...
                'RowName', {'Base 1','Base 2','Base 3','Platform 1','Platform 2','Platform 3'}, ...
                'Position', [10 10 280 230]);

           
        end

        % Button pushed function: GatherCurrentPlatformPoseButton, 
        % ...and 1 other component
        function GatherCurrentPlatformPoseButtonPushed(app, event)
            popup = uifigure('Name', 'Please Wait', 'Position', [500 500 300 100], ...
                 'WindowStyle', 'modal');
            uilabel(popup, 'Text', 'Collecting data and computing angles...', ...
                'Position', [20 40 260 22], 'FontSize', 14);
            drawnow;
            joint_angles =GatherArduinoData_BaseJoint();
            joint_angles(isnan(joint_angles)) = 0;
            close(popup);

            app.RollEditField_7.Value = joint_angles(1,1);
            app.PitchEditField_5.Value = joint_angles(1,2);

        end

        % Button pushed function: GatherCurrentPlatformPoseButton_2
        function GatherCurrentPlatformPoseButton_2Pushed(app, event)
           popup = uifigure('Name', 'Please Wait', 'Position', [500 500 300 100], ...
                 'WindowStyle', 'modal');
            uilabel(popup, 'Text', 'Collecting data and computing angles...', ...
                'Position', [20 40 260 22], 'FontSize', 14);
            drawnow;
            joint_angles =GatherArduinoData_BaseJoint();
            joint_angles(isnan(joint_angles)) = 0;
            close(popup);
              
            app.RydegEditField.Value = joint_angles(1,2);                 
            app.RxdegEditField.Value = joint_angles(1,1);
            Rx = joint_angles(1,1);
            Ry = joint_angles(1,2);
            pose = [0,0,0,deg2rad(Rx),deg2rad(Ry),0]';
            app.hex_obj.pose =pose;
            app.hex_obj = InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
            
            JointNumberSelected = app.JointNumberDropDown_3.Value;
            
            if startsWith(JointNumberSelected,'Base')
                numbBase = sscanf(JointNumberSelected,'Base #%d');
                switch numbBase
                    case 1
                        numbBase = 1;
                    case 2
                        numbBase = 3;
                    case 3
                        numbBase = 5;
                    otherwise
                        error('Unexpected numbBase value: %d', numbBase);
                end
                app.Theta = rad2deg(app.hex_obj.joint_AB.angle_theta(numbBase));
                app.Phi = rad2deg(app.hex_obj.joint_AB.angle_phi(numbBase));
                %app.updateMeshPlot(0);

            elseif startsWith(JointNumberSelected,'Platform')
                numbPlat = sscanf(JointNumberSelected,'Platform #%d');
                %numbPlat = numbPlat+3;
                switch numbPlat
                    case 1
                        numbPlat = 1;
                    case 2
                        numbPlat = 3;
                    case 3
                        numbPlat = 5;
                    otherwise
                        error('Unexpected numbBase value: %d', numbPlat);
                end
                
                % U-Joint 90 deg Rotation
                app.Theta = rad2deg(app.hex_obj.joint_CD.angle_psi(numbPlat));
                app.Phi = rad2deg(app.hex_obj.joint_CD.angle_alpha(numbPlat));
                %app.updateMeshPlot(1);
            end
            app.FirstAngleEditField.Value = app.Theta;
            app.SecondAngleEditField.Value = app.Phi;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'U-Joint Collision Mesh')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StewartPlatformUJointKinematicsCheckLabel = uilabel(app.UIFigure);
            app.StewartPlatformUJointKinematicsCheckLabel.Text = 'Stewart Platform U-Joint Kinematics Check';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializationTab = uitab(app.TabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.LoadHexapodGeometryButton = uibutton(app.InitializationTab, 'push');
            app.LoadHexapodGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHexapodGeometryButtonPushed, true);
            app.LoadHexapodGeometryButton.Text = 'Load Hexapod Geometry';
            app.ZeroJointAnglesButton = uibutton(app.InitializationTab, 'push');
            app.ZeroJointAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroJointAnglesButtonPushed, true);
            app.ZeroJointAnglesButton.Text = 'Zero Joint Angles';
            app.PleasecompletethefollowingLabel = uilabel(app.InitializationTab);
            app.PleasecompletethefollowingLabel.Text = 'Please complete the following:';
            app.LoadCustomUJointMeshButton = uibutton(app.InitializationTab, 'push');
            app.LoadCustomUJointMeshButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomUJointMeshButtonPushed, true);
            app.LoadCustomUJointMeshButton.Text = 'Load Custom U-Joint Mesh';
            app.HexapodCartesianPosePanel = uipanel(app.InitializationTab);
            app.HexapodCartesianPosePanel.Title = 'Hexapod Cartesian Pose';
            app.GatherCurrentPlatformPoseButton = uibutton(app.HexapodCartesianPosePanel, 'push');
            app.GatherCurrentPlatformPoseButton.ButtonPushedFcn = createCallbackFcn(app, @GatherCurrentPlatformPoseButtonPushed, true);
            app.GatherCurrentPlatformPoseButton.Text = {'Gather Current '; 'Platform Pose'};
            app.RollEditField_6Label = uilabel(app.HexapodCartesianPosePanel);
            app.RollEditField_6Label.Text = 'Roll';
            app.RollEditField_6 = uieditfield(app.HexapodCartesianPosePanel, 'numeric');
            app.RollEditField_6.ValueDisplayFormat = '%.2f';
            app.PitchEditField_4Label = uilabel(app.HexapodCartesianPosePanel);
            app.PitchEditField_4Label.Text = 'Pitch';
            app.PitchEditField_4 = uieditfield(app.HexapodCartesianPosePanel, 'numeric');
            app.PitchEditField_4.ValueDisplayFormat = '%.2f';
            app.ForTestingLabel = uilabel(app.InitializationTab);
            app.ForTestingLabel.Text = 'For Testing:';
            app.HexapodCartesianPosePanel_2 = uipanel(app.InitializationTab);
            app.HexapodCartesianPosePanel_2.Title = 'Hexapod Cartesian Pose';
            app.GatherCurrentPlatformPoseButton_3 = uibutton(app.HexapodCartesianPosePanel_2, 'push');
            app.GatherCurrentPlatformPoseButton_3.ButtonPushedFcn = createCallbackFcn(app, @GatherCurrentPlatformPoseButtonPushed, true);
            app.GatherCurrentPlatformPoseButton_3.Text = {'Gather Current '; 'Platform Pose'};
            app.RollEditField_7Label = uilabel(app.HexapodCartesianPosePanel_2);
            app.RollEditField_7Label.Text = 'Roll';
            app.RollEditField_7 = uieditfield(app.HexapodCartesianPosePanel_2, 'numeric');
            app.RollEditField_7.ValueDisplayFormat = '%.2f';
            app.PitchEditField_5Label = uilabel(app.HexapodCartesianPosePanel_2);
            app.PitchEditField_5Label.Text = 'Pitch';
            app.PitchEditField_5 = uieditfield(app.HexapodCartesianPosePanel_2, 'numeric');
            app.PitchEditField_5.ValueDisplayFormat = '%.2f';
            app.AtHomePosLabel = uilabel(app.InitializationTab);
            app.AtHomePosLabel.Text = 'At Home Pos:';
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.ComputedUJointPositionDataPanel = uipanel(app.StaticTab);
            app.ComputedUJointPositionDataPanel.Title = 'Computed U-Joint Position Data';
            app.RxdegLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.RxdegLabel.Text = {'Rx'; '[deg]'};
            app.RxdegEditField = uieditfield(app.ComputedUJointPositionDataPanel, 'numeric');
            app.RydegLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.RydegLabel.Text = {'Ry'; '[deg]'};
            app.RydegEditField = uieditfield(app.ComputedUJointPositionDataPanel, 'numeric');
            app.RzdegLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.RzdegLabel.Text = {'Rz '; '[deg]'};
            app.RzdegEditField = uieditfield(app.ComputedUJointPositionDataPanel, 'numeric');
            app.xmEditFieldLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.xmEditFieldLabel.Text = 'x [m]';
            app.xmEditField = uieditfield(app.ComputedUJointPositionDataPanel, 'numeric');
            app.ymEditFieldLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.ymEditFieldLabel.Text = 'y [m]';
            app.ymEditField = uieditfield(app.ComputedUJointPositionDataPanel, 'numeric');
            app.zmEditFieldLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.zmEditFieldLabel.Text = 'z [m]';
            app.zmEditField = uieditfield(app.ComputedUJointPositionDataPanel, 'numeric');
            app.EnterButton = uibutton(app.ComputedUJointPositionDataPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.KinematicOuputAnglesLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.KinematicOuputAnglesLabel.Text = {'Kinematic Ouput '; 'Angles'};
            app.FirstAngleEditFieldLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.FirstAngleEditFieldLabel.Text = 'First Angle';
            app.FirstAngleEditField = uieditfield(app.ComputedUJointPositionDataPanel, 'numeric');
            app.FirstAngleEditField.ValueDisplayFormat = '%.2f';
            app.SecondAngleEditFieldLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.SecondAngleEditFieldLabel.Text = 'Second Angle';
            app.SecondAngleEditField = uieditfield(app.ComputedUJointPositionDataPanel, 'numeric');
            app.SecondAngleEditField.ValueDisplayFormat = '%.2f';
            app.ORLabel = uilabel(app.ComputedUJointPositionDataPanel);
            app.ORLabel.Text = 'OR';
            app.GatherCurrentPlatformPoseButton_2 = uibutton(app.ComputedUJointPositionDataPanel, 'push');
            app.GatherCurrentPlatformPoseButton_2.ButtonPushedFcn = createCallbackFcn(app, @GatherCurrentPlatformPoseButton_2Pushed, true);
            app.GatherCurrentPlatformPoseButton_2.Text = {'Gather Current '; 'Platform Pose'};
            app.JointNumberDropDown_3Label = uilabel(app.ComputedUJointPositionDataPanel);
            app.JointNumberDropDown_3Label.Text = 'Joint Number';
            app.JointNumberDropDown_3 = uidropdown(app.ComputedUJointPositionDataPanel);
            app.JointNumberDropDown_3.Items = {'Base #1', 'Base #2', 'Base #3', 'Platform #1', 'Platform #2', 'Platform #3'};
            app.JointNumberDropDown_3.Value = 'Base #1';
            app.MeasuredUjointPositionDataPanel = uipanel(app.StaticTab);
            app.MeasuredUjointPositionDataPanel.Title = 'Measured U-joint Position Data ';
            app.CurrentJointPoseButton = uibutton(app.MeasuredUjointPositionDataPanel, 'push');
            app.CurrentJointPoseButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentJointPoseButtonPushed, true);
            app.CurrentJointPoseButton.Text = {'Current '; 'Joint Pose'};
            app.FirstAngleEditField_2Label = uilabel(app.MeasuredUjointPositionDataPanel);
            app.FirstAngleEditField_2Label.Text = 'First Angle';
            app.FirstAngleEditField_2 = uieditfield(app.MeasuredUjointPositionDataPanel, 'numeric');
            app.FirstAngleEditField_2.ValueDisplayFormat = '%.2f';
            app.FirstAngleEditField_2.Editable = 'off';
            app.degLabel_3 = uilabel(app.MeasuredUjointPositionDataPanel);
            app.degLabel_3.Text = 'deg';
            app.SecondAngleEditField_2Label = uilabel(app.MeasuredUjointPositionDataPanel);
            app.SecondAngleEditField_2Label.Text = 'Second Angle';
            app.SecondAngleEditField_2 = uieditfield(app.MeasuredUjointPositionDataPanel, 'numeric');
            app.SecondAngleEditField_2.ValueDisplayFormat = '%.2f';
            app.SecondAngleEditField_2.Editable = 'off';
            app.degLabel_4 = uilabel(app.MeasuredUjointPositionDataPanel);
            app.degLabel_4.Text = 'deg';
            app.DynamicTab = uitab(app.TabGroup);
            app.DynamicTab.Title = 'Dynamic';
            app.UIAxes2 = uiaxes(app.DynamicTab);
            title(app.UIAxes2, 'Minimum Distance')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SelectJointButtonGroup = uibuttongroup(app.DynamicTab);
            app.SelectJointButtonGroup.Title = 'Select Joint';
            app.Base1Button = uitogglebutton(app.SelectJointButtonGroup);
            app.Base1Button.Text = 'Base 1';
            app.Base1Button.Value = true;
            app.Base2Button = uitogglebutton(app.SelectJointButtonGroup);
            app.Base2Button.Text = 'Base 2';
            app.Base3Button = uitogglebutton(app.SelectJointButtonGroup);
            app.Base3Button.Text = 'Base 3';
            app.Platform1Button = uitogglebutton(app.SelectJointButtonGroup);
            app.Platform1Button.Text = 'Platform 1';
            app.Platform2Button = uitogglebutton(app.SelectJointButtonGroup);
            app.Platform2Button.Text = 'Platform 2';
            app.Platform3Button = uitogglebutton(app.SelectJointButtonGroup);
            app.Platform3Button.Text = 'Platform 3';
            app.StartStopButton = uibutton(app.DynamicTab, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start/ Stop';
            app.ExtrasTab = uitab(app.TabGroup);
            app.ExtrasTab.Title = 'Extras';
            app.AnimateJogUJointPanel = uipanel(app.ExtrasTab);
            app.AnimateJogUJointPanel.Title = 'Animate Jog U-Joint ';
            app.Label = uilabel(app.AnimateJogUJointPanel);
            app.Label.Text = 'First angle';
            app.FirstangleSlider = uislider(app.AnimateJogUJointPanel);
            app.FirstangleSlider.Limits = [0 90];
            app.FirstangleSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.FirstangleSlider.ValueChangingFcn = createCallbackFcn(app, @FirstangleSliderValueChanging, true);
            app.SecondangleSliderLabel = uilabel(app.AnimateJogUJointPanel);
            app.SecondangleSliderLabel.Text = 'Second angle';
            app.SecondangleSlider = uislider(app.AnimateJogUJointPanel);
            app.SecondangleSlider.Limits = [0 90];
            app.SecondangleSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.SecondangleSlider.ValueChangingFcn = createCallbackFcn(app, @SecondangleSliderValueChanging, true);
            app.PlatformJointCheckBox = uicheckbox(app.AnimateJogUJointPanel);
            app.PlatformJointCheckBox.Text = 'Platform Joint';
            app.DefaultisBaseJointLabel = uilabel(app.AnimateJogUJointPanel);
            app.DefaultisBaseJointLabel.Text = '(Default is Base Joint)';
            app.MinimumDistancemmGauge = uigauge(app.UIFigure, 'linear');
            app.MinimumDistancemmGauge.Limits = [0 5];
            app.MinimumDistancemmGaugeLabel = uilabel(app.UIFigure);
            app.MinimumDistancemmGaugeLabel.Text = 'Minimum Distance [mm]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StewartPlatform_JointKinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end